classdef ProyectoFinal_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DigitosmanuscritosUIFigure   matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        InicioTab                    matlab.ui.container.Tab
        Panel_2                      matlab.ui.container.Panel
        VolveragenerarButton         matlab.ui.control.Button
        imagenesseleccionadasaleatoriamenteLabel  matlab.ui.control.Label
        MainImage                    matlab.ui.control.UIAxes
        Panel                        matlab.ui.container.Panel
        SelectedImage                matlab.ui.control.UIAxes
        OutputEditFieldLabel         matlab.ui.control.Label
        OutputEditField              matlab.ui.control.NumericEditField
        AnteriorButton               matlab.ui.control.Button
        VerprediccionButton          matlab.ui.control.Button
        ImagenLabel                  matlab.ui.control.Label
        SiguienteButton              matlab.ui.control.Button
        RedneuronalTab               matlab.ui.container.Tab
        Panel_3                      matlab.ui.container.Panel
        VerdiagramaderedButton       matlab.ui.control.Button
        GuardarredactualButton       matlab.ui.control.Button
        RedactualLabel               matlab.ui.control.Label
        Panel_4                      matlab.ui.container.Panel
        TamaodecapaocultaLabel       matlab.ui.control.Label
        CapaOculta                   matlab.ui.control.Spinner
        FunciondeentrenamientoEditFieldLabel  matlab.ui.control.Label
        FuncionEntrenamiento         matlab.ui.control.EditField
        Button                       matlab.ui.control.Button
        EntrenamientoEditFieldLabel  matlab.ui.control.Label
        EntrenamientoData            matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        ValidacionEditFieldLabel     matlab.ui.control.Label
        ValidacionData               matlab.ui.control.NumericEditField
        PruebaEditFieldLabel         matlab.ui.control.Label
        PruebaData                   matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        FuncionderendimientoEditFieldLabel  matlab.ui.control.Label
        FuncionRendimiento           matlab.ui.control.EditField
        DivisiondelosdatosLabel      matlab.ui.control.Label
        Button_2                     matlab.ui.control.Button
        EntrenarRedBtn               matlab.ui.control.Button
        MaximosfallosEditFieldLabel  matlab.ui.control.Label
        FallosMax                    matlab.ui.control.NumericEditField
        EntrenarredLabel             matlab.ui.control.Label
        nntraintoolButton            matlab.ui.control.Button
    end


    properties (Access = private)
        input_data;
        target_data;
        test_data;
        network;
        
        selected_image = 1;
        selected_prediction;
        predicted_array;
    end

    methods (Access = private)
    
        function [] = displaySelectedImage(app)
            display_array = getDisplayArray(app.test_data(:,app.selected_image));
            image = saveImage(display_array, "selected");
            imshow(image,'Parent',app.SelectedImage);
            
            title = "Imagen " + app.selected_image + " de 100";
            app.ImagenLabel.Text = char(title);
            
            app.predicted_array = predict(app.selected_image, app.test_data, app.network);
            app.selected_prediction = getPrediction(app.predicted_array);
            app.OutputEditField.Value = app.selected_prediction;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.input_data, app.target_data, app.network] = initializeData();
            mainImageBtn(app);            
        end

        % Button pushed function: AnteriorButton
        function anteriorBtn(app, event)
            if app.selected_image > 1
                app.selected_image = app.selected_image - 1;
            end
            displaySelectedImage(app);
            
        end

        % Button pushed function: VerprediccionButton
        function detail(app, event)
            x = 1:10;
            bar(x, app.predicted_array);
            
            
        end

        % Button pushed function: EntrenarRedBtn
        function entrenarRed(app, event)
            capaOculta = app.CapaOculta.Value;
            fEntrenamiento = char(app.FuncionEntrenamiento.Value);           
            fRendimiento = char(app.FuncionRendimiento.Value);
            pEntrenamiento = app.EntrenamientoData.Value / 100;
            pValidacion = app.ValidacionData.Value / 100;
            pPrueba = app.PruebaData.Value / 100;
            maxFallos = app.FallosMax.Value;
            
            app.network = trainNetwork(app.input_data, app.target_data, app.network, capaOculta, fEntrenamiento, fRendimiento, pEntrenamiento, pValidacion, pPrueba, maxFallos);
        end

        % Button pushed function: GuardarredactualButton
        function guardarRed(app, event)
            saveNetwork(app.network);
            f = msgbox('Se ha guardado la red','Informacion');
        end

        % Button pushed function: VolveragenerarButton
        function mainImageBtn(app, event)
            % Generar mainImage
            [app.test_data, image] = generateTestData(app.input_data);
            imshow(image,'Parent',app.MainImage);
            
            app.selected_image = 1;
            displaySelectedImage(app);
        end

        % Callback function
        function nnperformance(app, event)
            doc nnperformance;
        end

        % Callback function
        function nnplot(app, event)
            doc nnplot;
        end

        % Callback function
        function nntool(app, event)
            nntool;
        end

        % Callback function
        function nntrain(app, event)
            doc nntrain;
        end

        % Button pushed function: SiguienteButton
        function siguienteBtn(app, event)
            if app.selected_image < 100
                app.selected_image = app.selected_image + 1;
            end
            displaySelectedImage(app);
        end

        % Button pushed function: nntraintoolButton
        function trainTool(app, event)
            nntraintool();
        end

        % Callback function
        function verPlot(app, event)
            str2func(app.Plot.Value());
        end

        % Button pushed function: VerdiagramaderedButton
        function viewNetwork(app, event)
            view(app.network);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DigitosmanuscritosUIFigure = uifigure;
            app.DigitosmanuscritosUIFigure.Name = 'Digitos manuscritos';
            app.TabGroup = uitabgroup(app.DigitosmanuscritosUIFigure);
            app.InicioTab = uitab(app.TabGroup);
            app.InicioTab.Title = 'Inicio';
            app.Panel_2 = uipanel(app.InicioTab);
            app.VolveragenerarButton = uibutton(app.Panel_2, 'push');
            app.VolveragenerarButton.ButtonPushedFcn = createCallbackFcn(app, @mainImageBtn, true);
            app.VolveragenerarButton.Text = 'Volver a generar';
            app.imagenesseleccionadasaleatoriamenteLabel = uilabel(app.Panel_2);
            app.imagenesseleccionadasaleatoriamenteLabel.Text = '100 imagenes seleccionadas aleatoriamente';
            app.MainImage = uiaxes(app.Panel_2);
            app.Panel = uipanel(app.InicioTab);
            app.SelectedImage = uiaxes(app.Panel);
            app.OutputEditFieldLabel = uilabel(app.Panel);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.Panel, 'numeric');
            app.OutputEditField.ValueDisplayFormat = '%.0f';
            app.OutputEditField.Editable = 'off';
            app.AnteriorButton = uibutton(app.Panel, 'push');
            app.AnteriorButton.ButtonPushedFcn = createCallbackFcn(app, @anteriorBtn, true);
            app.AnteriorButton.Text = 'Anterior';
            app.VerprediccionButton = uibutton(app.Panel, 'push');
            app.VerprediccionButton.ButtonPushedFcn = createCallbackFcn(app, @detail, true);
            app.VerprediccionButton.Text = 'Ver prediccion';
            app.ImagenLabel = uilabel(app.Panel);
            app.ImagenLabel.Text = 'Imagen 1 de 100';
            app.SiguienteButton = uibutton(app.Panel, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @siguienteBtn, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.RedneuronalTab = uitab(app.TabGroup);
            app.RedneuronalTab.Title = 'Red neuronal';
            app.Panel_3 = uipanel(app.RedneuronalTab);
            app.VerdiagramaderedButton = uibutton(app.Panel_3, 'push');
            app.VerdiagramaderedButton.ButtonPushedFcn = createCallbackFcn(app, @viewNetwork, true);
            app.VerdiagramaderedButton.Text = 'Ver diagrama de red';
            app.GuardarredactualButton = uibutton(app.Panel_3, 'push');
            app.GuardarredactualButton.ButtonPushedFcn = createCallbackFcn(app, @guardarRed, true);
            app.GuardarredactualButton.Text = 'Guardar red actual';
            app.RedactualLabel = uilabel(app.Panel_3);
            app.RedactualLabel.Text = 'Red actual';
            app.Panel_4 = uipanel(app.RedneuronalTab);
            app.TamaodecapaocultaLabel = uilabel(app.Panel_4);
            app.TamaodecapaocultaLabel.Text = 'Tamaño de capa oculta';
            app.CapaOculta = uispinner(app.Panel_4);
            app.CapaOculta.Value = 25;
            app.FunciondeentrenamientoEditFieldLabel = uilabel(app.Panel_4);
            app.FunciondeentrenamientoEditFieldLabel.Text = 'Funcion de entrenamiento';
            app.FuncionEntrenamiento = uieditfield(app.Panel_4, 'text');
            app.FuncionEntrenamiento.Value = 'trainscg';
            app.Button = uibutton(app.Panel_4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @nntrain, true);
            app.Button.Text = '?';
            app.EntrenamientoEditFieldLabel = uilabel(app.Panel_4);
            app.EntrenamientoEditFieldLabel.Text = 'Entrenamiento';
            app.EntrenamientoData = uieditfield(app.Panel_4, 'numeric');
            app.EntrenamientoData.Value = 80;
            app.Label = uilabel(app.Panel_4);
            app.Label.Text = '%';
            app.ValidacionEditFieldLabel = uilabel(app.Panel_4);
            app.ValidacionEditFieldLabel.Text = 'Validacion';
            app.ValidacionData = uieditfield(app.Panel_4, 'numeric');
            app.ValidacionData.Value = 10;
            app.PruebaEditFieldLabel = uilabel(app.Panel_4);
            app.PruebaEditFieldLabel.Text = 'Prueba';
            app.PruebaData = uieditfield(app.Panel_4, 'numeric');
            app.PruebaData.Value = 10;
            app.Label_2 = uilabel(app.Panel_4);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.Panel_4);
            app.Label_3.Text = '%';
            app.FuncionderendimientoEditFieldLabel = uilabel(app.Panel_4);
            app.FuncionderendimientoEditFieldLabel.Text = 'Funcion de rendimiento';
            app.FuncionRendimiento = uieditfield(app.Panel_4, 'text');
            app.FuncionRendimiento.Value = 'crossentropy';
            app.DivisiondelosdatosLabel = uilabel(app.Panel_4);
            app.DivisiondelosdatosLabel.Text = 'Division de los datos:';
            app.Button_2 = uibutton(app.Panel_4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @nnperformance, true);
            app.Button_2.Text = '?';
            app.EntrenarRedBtn = uibutton(app.Panel_4, 'push');
            app.EntrenarRedBtn.ButtonPushedFcn = createCallbackFcn(app, @entrenarRed, true);
            app.EntrenarRedBtn.Text = 'Entrenar red';
            app.MaximosfallosEditFieldLabel = uilabel(app.Panel_4);
            app.MaximosfallosEditFieldLabel.Text = 'Maximos fallos';
            app.FallosMax = uieditfield(app.Panel_4, 'numeric');
            app.FallosMax.Value = 6;
            app.EntrenarredLabel = uilabel(app.Panel_4);
            app.EntrenarredLabel.Text = 'Entrenar red';
            app.nntraintoolButton = uibutton(app.Panel_4, 'push');
            app.nntraintoolButton.ButtonPushedFcn = createCallbackFcn(app, @trainTool, true);
            app.nntraintoolButton.Text = 'nntraintool';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProyectoFinal_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DigitosmanuscritosUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DigitosmanuscritosUIFigure)
        end
    end
end